classdef GUIMatlab_App_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1             matlab.ui.Figure
        uipanel1            matlab.ui.container.Panel
        text2               matlab.ui.control.Label
        text3               matlab.ui.control.Label
        Label_2             matlab.ui.control.Label
        TeganganGaugeLabel  matlab.ui.control.Label
        TeganganGauge       matlab.ui.control.NinetyDegreeGauge
        uipanel3            matlab.ui.container.Panel
        pir                 matlab.ui.control.UIAxes
        text4               matlab.ui.control.Label
        text5               matlab.ui.control.Label
        Label               matlab.ui.control.Label
        pushbutton3         matlab.ui.control.Button
        start               matlab.ui.control.Button
        serial              matlab.ui.control.Label
    end

    
    methods (Access = private)
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function GUIMatlab_OpeningFcn(app)
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             % varargin   command line arguments to GUIMatlab (see VARARGIN)
%             
%             % Choose default command line output for GUIMatlab
%             app.output = hObject;
%             
%             axes(app.pir)
%             guidata(hObject,handles);
%             
%             
%             % Update handles structure
%             guidata(hObject, handles);
%             delete(instrfind({'Port'},{'COM9'}))
        end

        % Button pushed function: start
        function start_Callback(app, event)
                c=0;
                app.start.Text = 'Stop';
                delete(instrfind({'Port'},{'COM9'}));
                ser = serial('COM9','BAUD', 9600);
                fopen(ser);
                n=10;
                while 1
                    
                    y = fscanf(ser);
                    app.serial.Text = y
                    datas = strsplit(y,':');
                    x= str2double(datas);
                    app.TeganganGauge.Value = round(x(1,1));                  
                    app.text3.Text =  datas(1,1);
                    app.text5.Text =  datas(1,2);
                    if x(1,1) < 5
                        app.Label_2.Text = 'Aman';
                    else
                        app.Label_2.Text = 'Bahaya';
                    end
            
                    if x(1,3) == 1     
                        app.Label.Text = 'Ada Orang';
                        
                        imshow('Orang.jpg','Parent',app.pir);
                    else
                        app.Label.Text = 'Kosong';
                        %axes(app.pir)
                        imshow('empty.jpg','Parent',app.pir);
                    end
                    pause(0.01);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure;
            app.figure1.Name = 'GUIMatlab';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Sensor Tegangan';
            app.text2 = uilabel(app.uipanel1);
            app.text2.Text = 'Nilai Tegangan';
            app.text3 = uilabel(app.uipanel1);
            app.text3.Text = 'Data Volt';
            app.Label_2 = uilabel(app.uipanel1);
            app.Label_2.Text = ' ';
            app.TeganganGaugeLabel = uilabel(app.uipanel1);
            app.TeganganGaugeLabel.Text = 'Tegangan';
            app.TeganganGauge = uigauge(app.uipanel1, 'ninetydegree');
            app.TeganganGauge.Limits = [0 10];
            app.uipanel3 = uipanel(app.figure1);
            app.uipanel3.Title = 'Sensor PIR';
            app.pir = uiaxes(app.uipanel3);
            app.text4 = uilabel(app.uipanel3);
            app.text4.Text = 'Banyaknya Orang';
            app.text5 = uilabel(app.uipanel3);
            app.text5.Text = 'Count';
            app.Label = uilabel(app.uipanel3);
            app.Label.Text = ' ';
            app.pushbutton3 = uibutton(app.figure1, 'push');
            app.pushbutton3.Text = 'Exit';
            app.start = uibutton(app.figure1, 'push');
            app.start.ButtonPushedFcn = createCallbackFcn(app, @start_Callback, true);
            app.start.Text = 'Start';
            app.serial = uilabel(app.figure1);
            app.serial.Text = ' ';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUIMatlab_App_1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @GUIMatlab_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end